classdef GUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1              matlab.ui.Figure
        button_identify      matlab.ui.control.Button
        uipanel3             matlab.ui.container.Panel
        text3                matlab.ui.control.Label
        uipanel1             matlab.ui.container.Panel
        text_result          matlab.ui.control.EditField
        text_path_image      matlab.ui.control.EditField
        button_browse_image  matlab.ui.control.Button
        layer_image          matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function edit3_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to GUI (see VARARGIN)
            
            % Choose default command line output for GUI
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            movegui(hObject, 'center');
            set(handles.figure1, 'Name', 'Fruit Classification');
        end

        % Button pushed function: button_browse_image
        function button_browse_image_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global fullname;
            [rawname, rawpath] = uigetfile({'*.jpg'},'Select Image Data');
            if ~isequal(rawname,0)
                fullname = [rawpath rawname];
                set(handles.text_path_image, 'String', fullname);
            
                im = imread(fullname);
                axes(handles.layer_image);
                imagesc(im);
            
                set(handles.button_identify,'Enable','on')
            else
                return
            end
        end

        % Button pushed function: button_identify
        function button_identify_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global fullname;
            
            if isfile('model_classification.mat')
                CompactMdl = loadLearnerForCoder('model_classification');
                Img = imread(fullname);
                cform = makecform('srgb2lab');
                lab = applycform(Img,cform);
                b = lab(:,:,2);
                bw = b>140;
                bw = imfill(bw,'holes');
                hsv = rgb2hsv(Img);
                h = hsv(:,:,1);
                s = hsv(:,:,2);
                h(~bw) = 0;
                s(~bw) = 0;
                ciri_h(1) = mean(mean(h));
                ciri_s(1) = mean(mean(s));
            
                sample = [ciri_h ciri_s];
                res = predict(CompactMdl,sample);
            
                set(handles.text_result, 'String', res);
            else
                set(handles.text_result, 'String', "Jalankan training img_classification dahulu");
                return
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GUI';
            app.figure1.Tag = 'figure1';
            app.layer_image = uiaxes(app.figure1);
            app.layer_image.Tag = 'layer_image';
            app.button_browse_image = uibutton(app.figure1, 'push');
            app.button_browse_image.ButtonPushedFcn = createCallbackFcn(app, @button_browse_image_Callback, true);
            app.button_browse_image.Tag = 'button_browse_image';
            app.button_browse_image.Text = 'Browse Image';
            app.text_path_image = uieditfield(app.figure1, 'text');
            app.text_path_image.Tag = 'text_path_image';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Result Classification';
            app.uipanel1.Tag = 'uipanel1';
            app.text_result = uieditfield(app.uipanel1, 'text');
            app.text_result.Tag = 'text_result';
            app.text_result.Value = 'Result';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Tag = 'uipanel3';
            app.text3 = uilabel(app.uipanel3);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Irfan Haris Widyadhana ~ Dandi Agus Maulana ~ Anzaldi Sulaiman Oemar';
            app.button_identify = uibutton(app.figure1, 'push');
            app.button_identify.ButtonPushedFcn = createCallbackFcn(app, @button_identify_Callback, true);
            app.button_identify.Tag = 'button_identify';
            app.button_identify.Text = 'Identify';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)GUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end